classdef RingTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RingTracker3DUIFigure         matlab.ui.Figure
        ProgressImage                 matlab.ui.control.Image
        zscale                        matlab.ui.control.NumericEditField
        zscalemumEditFieldLabel       matlab.ui.control.Label
        xscale                        matlab.ui.control.NumericEditField
        xscalemumEditFieldLabel       matlab.ui.control.Label
        yscale                        matlab.ui.control.NumericEditField
        yscalemumEditField_2Label     matlab.ui.control.Label
        FrameSlider                   matlab.ui.control.Slider
        FrameLabel                    matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        SetupTab                      matlab.ui.container.Tab
        LocateSwitch                  matlab.ui.control.Switch
        LocateSwitchLabel             matlab.ui.control.Label
        ChannelDropDown               matlab.ui.control.DropDown
        ChannelDropDownLabel          matlab.ui.control.Label
        SelectTiffStackButton         matlab.ui.control.Button
        SidePreview                   matlab.ui.control.UIAxes
        TopDownPreview                matlab.ui.control.UIAxes
        TrackRingTab                  matlab.ui.container.Tab
        TrackingControls              matlab.ui.container.Panel
        ring_width                    matlab.ui.control.NumericEditField
        RingWidthmumEditFieldLabel    matlab.ui.control.Label
        manualalignButton             matlab.ui.control.Button
        FluoPercentile                matlab.ui.control.NumericEditField
        FluoPercentileEditFieldLabel  matlab.ui.control.Label
        AutoscaleROICheckBox          matlab.ui.control.CheckBox
        RunButton_2                   matlab.ui.control.Button
        UIAxesRing3D                  matlab.ui.control.UIAxes
        UIAxesRing2D                  matlab.ui.control.UIAxes
        UIAxesFull3D                  matlab.ui.control.UIAxes
        ReportTab                     matlab.ui.container.Tab
        CellDropDown                  matlab.ui.control.DropDown
        CellDropDownLabel             matlab.ui.control.Label
        ImportCellBoundaryDataButton  matlab.ui.control.Button
        YDropDown                     matlab.ui.control.DropDown
        YDropDownLabel                matlab.ui.control.Label
        XDropDown                     matlab.ui.control.DropDown
        XDropDownLabel                matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        data % 4D data array
        N_frames % size(data,4)
        N_channels % size(data,5)
        channel % channel to display/analyze
        TopDownImage
        SideImage
        cmap = 'parula';
        TopDownROI
        SideROI
        scale % Description
        x0=[0.0372, 2.7652];
        pos_inds
        all_pos
    end


    methods (Access = private)

        function PreviewFrame(app, frame_number)
            if nargin==1
                frame_number=round(app.FrameSlider.Value);
            end
            frame_data = app.data(:,:,:,frame_number,app.channel);
            app.TopDownImage.CData = mip(frame_data);
            if isempty(app.TopDownROI)
                app.SideImage.CData = mip(frame_data,2)';
            else
                SideView(app, get(app.TopDownROI,'position'), frame_number)
            end

        end

        function addTopDownROI(app, event)
            if isempty(app.TopDownROI) || ~isgraphics(app.TopDownROI)
                ax=app.TopDownPreview;
                bounds=[xlim(ax);ylim(ax)];
                pt = event.IntersectionPoint;
                pos = [pt(1), pt(2),(bounds(1,2)-pt(1))/2,(bounds(2,2)-pt(2))/2];
                roi = drawrectangle(ax,'Position',pos,'Color','y','LineWidth',1);
                app.TopDownROI = roi;
                SideView(app,pos)
                addlistener(roi,'MovingROI',createCallbackFcn(app, @TopROIEvent, true));
                addlistener(roi,'ROIMoved',createCallbackFcn(app, @TopROIEvent, true));

                set(app.SideImage,'ButtonDownFcn',createCallbackFcn(app, @addSideROI, true));
            end
        end
        function addSideROI(app, event)
            ax=app.SidePreview;
            bounds=[xlim(ax);ylim(ax)];
            pt = event.IntersectionPoint;
            pos = [pt(1), pt(2),(bounds(1,2)-pt(1))/2,(bounds(2,2)-pt(2))/2];
            roi = drawrectangle(ax,'Position',pos,'Color','y','LineWidth',1);
            app.SideROI = roi;
        end


        function SideView(app, rectxy, frame)
            ix=floor(rectxy(1));
            lx=ceil(rectxy(3));
            iy=floor(rectxy(2));
            ly=ceil(rectxy(4));

            [~,max_spread]=max(rectxy(3:4));
            if nargin==2
                frame = round(app.FrameSlider.Value);
            end
            data = mip(app.data(iy:(iy+ly),ix:(ix+lx),:,frame,app.channel), max_spread)';


            app.SideImage.CData = data;

            app.SidePreview.XLim=[1, size(data,2)];


        end

        function setScale(app)
            app.scale=[app.xscale.Value, app.yscale.Value, app.zscale.Value];
            app.all_pos = app.pos_inds .* app.scale;
        end

        function TopROIEvent(app, evt)
            SideView(app, evt.CurrentPosition);
        end

        function plotFitRing(app,data, objectsPrev, objectSize)
            persistent AZ EL Ring2DLine Ring3DLine Full3DLine

                phi=data.phi;
                theta=data.theta;
                XY=data.XY;
                Z=data.Z;


                Zbar=mean(Z);

                near_ring = abs(Z-Zbar)<app.ring_width.Value;


                p=EllipseDirectFit(XY(near_ring,:));
                xysamp=sampleEllipse2D(p);

                Ropt=[r_hat(phi,theta)', phi_hat(phi,theta)', theta_hat(phi,theta)'];

                xyzsamp=[xysamp+p(end-1:end), repmat(Zbar,[size(xysamp,1),1])];
%                 xyzsamp = xyzsamp + [0,0, Zbar];

                xyzsamp_orig= (Ropt*xyzsamp')' +(objectsPrev-objectSize).*app.scale ;


                hold(app.UIAxesRing2D,'on');

                if isempty(Ring2DLine)
                    Ring2DLine=plot(app.UIAxesRing2D,xysamp(:,1)+p(end-1),xysamp(:,2)+p(end),'LineStyle','-','Color','g','LineWidth',3);
                else
                    Ring2DLine.XData=xysamp(:,1)+p(end-1);
                    Ring2DLine.YData=xysamp(:,2)+p(end);
                end

                hold(app.UIAxesRing2D,'off')


                hold(app.UIAxesRing3D,'on')
                
                if isempty(Ring3DLine)
                    Ring3DLine=plot3(app.UIAxesRing3D, xyzsamp(:,1),xyzsamp(:,2),xyzsamp(:,3),'-g', 'LineWidth', 3);
                else
                    Ring3DLine.XData=xyzsamp(:,1);
                    Ring3DLine.YData=xyzsamp(:,2);
                    Ring3DLine.ZData=xyzsamp(:,3);
                end
                % plot3(ax2, xyzsamp0(:,1),xyzsamp0(:,2),xyzsamp0(:,3)+Zbar,'--k')
                hold(app.UIAxesRing3D,'off')
                

                hold(app.UIAxesFull3D,'on')
                title(app.UIAxesFull3D,'Full 3D View')
                if isempty(Full3DLine)
                    Full3DLine=plot3(app.UIAxesFull3D, xyzsamp_orig(:,1),xyzsamp_orig(:,2),-xyzsamp_orig(:,3),'-g', 'LineWidth', 3);
                else
                    Full3DLine.XData=xyzsamp_orig(:,1);
                    Full3DLine.YData=xyzsamp_orig(:,2);
                    Full3DLine.ZData=xyzsamp_orig(:,3);
                end
                hold(app.UIAxesFull3D,'off')
%                 xlabel(app.UIAxesFull3D,'X');ylabel(app.UIAxesFull3D,'Y');zlabel(app.UIAxesFull3D,'Z');
                
                if ~isempty(AZ)
                    view(app.UIAxesFull3D,AZ,EL)
                else
                    view(app.UIAxesFull3D,45,60)
                end


%                 drawnow
                % pause(4)
                [AZ,EL] = view(app.UIAxesFull3D);

        end

        function plotRingRawData(app, data)
            persistent Ring2DImage Ring3DImage Full3DImage
            intensity = data.intensity;
            full_vol = data.full_vol;

            XY=data.XY;
            Z=data.Z;
            
           if isempty(Ring2DImage)
                Ring2DImage = scatter(app.UIAxesRing2D, XY(:,1),XY(:,2),'.');
           else
                Ring2DImage.XData = XY(:,1);
                Ring2DImage.YData = XY(:,2);
           end
            markersize=24*scaleVals(intensity,0.5);
            if isempty(Ring3DImage)
                Ring3DImage = scatter3(app.UIAxesRing3D, XY(:,1),XY(:,2),Z,'Marker','.', 'SizeData', markersize);
            else
                Ring3DImage.XData = XY(:,1);
                Ring3DImage.YData = XY(:,2);
                Ring3DImage.ZData = Z;
                Ring3DImage.SizeData = markersize;
            end
            bright = full_vol(:)>=intensity(1);
            bright_pos = app.all_pos(bright,:);
            markersize=24*scaleVals(full_vol(bright),0.5);
            if isempty(Full3DImage)
                Full3DImage = scatter3(app.UIAxesFull3D, bright_pos(:,1),bright_pos(:,2), -bright_pos(:,3),'Marker','.', 'SizeData', markersize);
            else
                Full3DImage.XData = bright_pos(:,1);
                Full3DImage.YData = bright_pos(:,2);
                Full3DImage.ZData = -bright_pos(:,3);
                Full3DImage.SizeData = markersize;
            end
        end

        function results=processRawRingData(app, frame_number, objectsPrev, objectSize)
        
                full_vol = app.data(:,:,:,frame_number,app.channel);
                ring_vol = getVol(full_vol, objectsPrev, objectSize);
                % ring_vol = data(:,:,:,34,1);
                pos = getPos(ring_vol)-1;
                [~, i_sorted] =  sort(ring_vol(:));

                %%
                N_take = round(app.FluoPercentile.Value*length(i_sorted)/100);
                i_take = i_sorted(end-(N_take-1):end);
                intensity = ring_vol(i_take);
                pos_take = pos(i_take,:).*app.scale;
                
                
                f=@(phi,theta) var(theta_hat(phi,theta) * pos_take');
                xopt=fminsearch(@(x) f(x(1),x(2)),app.x0);
               

                phi=xopt(1);
                theta=xopt(2);

                app.x0=xopt;

                XY=[r_hat(phi,theta) * pos_take'; phi_hat(phi,theta) * pos_take']';


                Z=(theta_hat(phi,theta) * pos_take')';
                
                results=struct('intensity', intensity,'full_vol',full_vol,'XY',XY,'Z',Z,'phi',phi,'theta',theta);

              
        end

    
    end

    methods (Static)
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectTiffStackButton
        function SelectTiffStackButtonPushed(app, event)
            [nm, p]=uigetfile('*.tif','Select image file');
            fn=[p nm];
            data = read_tiff(fn);
            app.data = data;
            app.pos_inds = getPos(app.data(:,:,:,1,1));
            disp('Done loading tiff stack')


            app.N_frames=size(data,4);
            app.FrameSlider.Enable=true;
            app.FrameLabel.Enable=true;
            app.FrameSlider.Limits=[1,app.N_frames];

            N_ticks = min(app.N_frames,20);
            MajorTicks=round(linspace(1,app.N_frames,N_ticks));
            app.FrameSlider.MajorTicks=MajorTicks;
            app.FrameSlider.MinorTicks=round(interp1(1:N_ticks,MajorTicks,1:0.2:N_ticks));

            app.channel=1;
            app.TopDownPreview.XLim=[1, size(data,2)];
            app.TopDownPreview.YLim=[0.5, size(data,1)];

            app.SidePreview.YLim=[0.5, size(data,3)];
            app.SidePreview.XLim=[1, size(data,1)];

            app.SidePreview.Visible=true;
            app.TopDownPreview.Visible=true;

            frame_data = app.data(:,:,:,1,app.channel);

            app.TopDownImage = imagesc(app.TopDownPreview,mip(frame_data), 'ButtonDownFcn',createCallbackFcn(app, @addTopDownROI, true));
            colormap(app.TopDownPreview,app.cmap)
            app.SideImage = imagesc(app.SidePreview,mip(frame_data, 2)');
            disableDefaultInteractivity(app.SidePreview)
            colormap(app.SidePreview,app.cmap)

            setScale(app);
            

        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = app.FrameSlider.Value;
            PreviewFrame(app,round(value))
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            PreviewFrame(app, round(event.Value))
        end

        % Button down function: TopDownPreview
        function TopDownPreviewButtonDown(app, event)
            disp('this is the button down callback for the top-down preview panel')
        end

        % Button down function: SidePreview
        function SidePreviewButtonDown(app, event)
            disp('side clicked')
        end

        % Button down function: TrackRingTab
        function TrackRingTabButtonDown(app, event)
            rectxy=get(app.TopDownROI,'position');
            rect2=get(app.SideROI,'position');
            coords = [sum(rectxy([1,1,3])) sum(rectxy([2,2,4])) sum(rect2([2,2,4]))]/2;

            ax1=app.UIAxesRing2D;
            
            ax2=app.UIAxesRing3D;
            ax3=app.UIAxesFull3D;

            title(ax2,'Ring-Centered 3D View')
            xlabel(ax2,'$\hat{x}$','interpreter','latex');
            ylabel(ax2,'$\hat{y}$','interpreter','latex');
            zlabel(ax2,'$\hat{z}$','interpreter','latex');

            objectsPrev=coords+0.5+[0 0 0];
            objectSize=[rectxy(3:4) rect2(4)]/2;

            objectsPrev = objectsPrev([2,1,3]);
            objectSize = objectSize([2,1,3]);


            start_frame=round(app.FrameSlider.Value);
    

            xlim(app.UIAxesFull3D,[0,size(app.data,1)]*app.scale(1))
            ylim(app.UIAxesFull3D,[0,size(app.data,2)]*app.scale(2))
            zlim(app.UIAxesFull3D,[-size(app.data,3),0]*app.scale(3))


            for i=start_frame:app.N_frames-1
                tic;
                data = processRawRingData(app, i, objectsPrev, objectSize);
                plotRingRawData(app, data);
                plotFitRing(app, data, objectsPrev, objectSize);
                toc
                drawnow limitrate

            end
            disp('done')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RingTracker3DUIFigure = uifigure('Visible', 'off');
            app.RingTracker3DUIFigure.Name = 'Ring Tracker 3D';
            app.TabGroup = uitabgroup(app.RingTracker3DUIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.TopDownPreview = uiaxes(app.SetupTab);
            title(app.TopDownPreview, 'Max. Int. Proj. (xy-view) - Please place ROI around ring')
            xlabel(app.TopDownPreview, 'X (pixels)')
            ylabel(app.TopDownPreview, 'Y (pixels)')
            zlabel(app.TopDownPreview, 'Z')
            app.TopDownPreview.ButtonDownFcn = createCallbackFcn(app, @TopDownPreviewButtonDown, true);
            app.TopDownPreview.Visible = 'off';
            app.SidePreview = uiaxes(app.SetupTab);
            title(app.SidePreview, 'Max. Int. Proj. (xz-view) - Please place ROI around ring')
            xlabel(app.SidePreview, 'X (pixels)')
            ylabel(app.SidePreview, 'Z (pixels)')
            zlabel(app.SidePreview, 'Z')
            app.SidePreview.ButtonDownFcn = createCallbackFcn(app, @SidePreviewButtonDown, true);
            app.SidePreview.Visible = 'off';
            app.SelectTiffStackButton = uibutton(app.SetupTab, 'push');
            app.SelectTiffStackButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTiffStackButtonPushed, true);
            app.SelectTiffStackButton.Text = 'Select Tiff Stack';
            app.ChannelDropDownLabel = uilabel(app.SetupTab);
            app.ChannelDropDownLabel.Enable = 'off';
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.SetupTab);
            app.ChannelDropDown.Items = {'1', '2', '3', '4'};
            app.ChannelDropDown.Enable = 'off';
            app.ChannelDropDown.Placeholder = '-';
            app.ChannelDropDown.Value = '1';
            app.LocateSwitchLabel = uilabel(app.SetupTab);
            app.LocateSwitchLabel.Enable = 'off';
            app.LocateSwitchLabel.Text = 'Locate';
            app.LocateSwitch = uiswitch(app.SetupTab, 'slider');
            app.LocateSwitch.Items = {'Ring', 'Centrosomes'};
            app.LocateSwitch.Enable = 'off';
            app.LocateSwitch.Value = 'Ring';
            app.TrackRingTab = uitab(app.TabGroup);
            app.TrackRingTab.Title = 'Track Ring';
            app.TrackRingTab.ButtonDownFcn = createCallbackFcn(app, @TrackRingTabButtonDown, true);
            app.UIAxesFull3D = uiaxes(app.TrackRingTab);
            title(app.UIAxesFull3D, 'Full 3D View')
            xlabel(app.UIAxesFull3D, 'X')
            ylabel(app.UIAxesFull3D, 'Y')
            zlabel(app.UIAxesFull3D, 'Z')
            app.UIAxesRing2D = uiaxes(app.TrackRingTab);
            title(app.UIAxesRing2D, '2D Projection')
            xlabel(app.UIAxesRing2D, 'X')
            ylabel(app.UIAxesRing2D, 'Y')
            zlabel(app.UIAxesRing2D, 'Z')
            app.UIAxesRing3D = uiaxes(app.TrackRingTab);
            title(app.UIAxesRing3D, 'Ring-Centered 3D View')
            xlabel(app.UIAxesRing3D, 'X')
            ylabel(app.UIAxesRing3D, 'Y')
            zlabel(app.UIAxesRing3D, 'Z')
            app.TrackingControls = uipanel(app.TrackRingTab);
            app.TrackingControls.Title = 'Tracking Controls';
            app.RunButton_2 = uibutton(app.TrackingControls, 'push');
            app.RunButton_2.Text = 'Run';
            app.AutoscaleROICheckBox = uicheckbox(app.TrackingControls);
            app.AutoscaleROICheckBox.Text = 'Autoscale ROI';
            app.FluoPercentileEditFieldLabel = uilabel(app.TrackingControls);
            app.FluoPercentileEditFieldLabel.Interpreter = 'latex';
            app.FluoPercentileEditFieldLabel.Text = 'Fluo. Percentile';
            app.FluoPercentile = uieditfield(app.TrackingControls, 'numeric');
            app.FluoPercentile.Limits = [0 100];
            app.FluoPercentile.Value = 5;
            app.manualalignButton = uibutton(app.TrackingControls, 'push');
            app.manualalignButton.Text = 'manual align';
            app.RingWidthmumEditFieldLabel = uilabel(app.TrackingControls);
            app.RingWidthmumEditFieldLabel.Interpreter = 'latex';
            app.RingWidthmumEditFieldLabel.Text = 'Ring Width ($\mu$m)';
            app.ring_width = uieditfield(app.TrackingControls, 'numeric');
            app.ring_width.Limits = [0 Inf];
            app.ring_width.Value = 2;
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.UIAxes4 = uiaxes(app.ReportTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.XDropDownLabel = uilabel(app.ReportTab);
            app.XDropDownLabel.Text = 'X';
            app.XDropDown = uidropdown(app.ReportTab);
            app.YDropDownLabel = uilabel(app.ReportTab);
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.ReportTab);
            app.ImportCellBoundaryDataButton = uibutton(app.ReportTab, 'push');
            app.ImportCellBoundaryDataButton.Text = 'Import Cell Boundary Data';
            app.CellDropDownLabel = uilabel(app.ReportTab);
            app.CellDropDownLabel.Enable = 'off';
            app.CellDropDownLabel.Text = 'Cell #';
            app.CellDropDown = uidropdown(app.ReportTab);
            app.CellDropDown.Enable = 'off';
            app.CellDropDown.Visible = 'off';
            app.FrameLabel = uilabel(app.RingTracker3DUIFigure);
            app.FrameLabel.Enable = 'off';
            app.FrameLabel.Text = 'Frame #';
            app.FrameSlider = uislider(app.RingTracker3DUIFigure);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Enable = 'off';
            app.FrameSlider.Value = 1;
            app.yscalemumEditField_2Label = uilabel(app.RingTracker3DUIFigure);
            app.yscalemumEditField_2Label.Interpreter = 'latex';
            app.yscalemumEditField_2Label.Enable = 'off';
            app.yscalemumEditField_2Label.Text = 'y-scale ($\mu$m)';
            app.yscale = uieditfield(app.RingTracker3DUIFigure, 'numeric');
            app.yscale.Limits = [0 Inf];
            app.yscale.Enable = 'off';
            app.yscale.Value = 0.183;
            app.xscalemumEditFieldLabel = uilabel(app.RingTracker3DUIFigure);
            app.xscalemumEditFieldLabel.Interpreter = 'latex';
            app.xscalemumEditFieldLabel.Enable = 'off';
            app.xscalemumEditFieldLabel.Text = 'x-scale ($\mu$m)';
            app.xscale = uieditfield(app.RingTracker3DUIFigure, 'numeric');
            app.xscale.Limits = [0 Inf];
            app.xscale.Enable = 'off';
            app.xscale.Value = 0.183;
            app.zscalemumEditFieldLabel = uilabel(app.RingTracker3DUIFigure);
            app.zscalemumEditFieldLabel.Interpreter = 'latex';
            app.zscalemumEditFieldLabel.Enable = 'off';
            app.zscalemumEditFieldLabel.Text = 'z-scale ($\mu$m)';
            app.zscale = uieditfield(app.RingTracker3DUIFigure, 'numeric');
            app.zscale.Limits = [0 Inf];
            app.zscale.Enable = 'off';
            app.zscale.Value = 1;
            app.ProgressImage = uiimage(app.RingTracker3DUIFigure);
            app.ProgressImage.ImageSource = fullfile(pathToMLAPP, 'transparent.png');
            % Show the figure after all components are created
            app.RingTracker3DUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RingTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RingTracker3DUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RingTracker3DUIFigure)
        end
    end
end