classdef RingTrackerGrided < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppUIFigure                   matlab.ui.Figure
        MasterGrid                    matlab.ui.container.GridLayout
        ControlGrid                   matlab.ui.container.GridLayout
        ScaleGrid                     matlab.ui.container.GridLayout
        zscaleLabel                   matlab.ui.control.Label
        xscale                        matlab.ui.control.NumericEditField
        yscale                        matlab.ui.control.NumericEditField
        yscaleLabel                   matlab.ui.control.Label
        zscale                        matlab.ui.control.NumericEditField
        xscaleLabel                   matlab.ui.control.Label
        FrameGrid                     matlab.ui.container.GridLayout
        ProgressImage                 matlab.ui.control.Image
        FitProgressLabel              matlab.ui.control.Label
        FrameSlider                   matlab.ui.control.Slider
        FrameSliderLabel              matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        SetupTab                      matlab.ui.container.Tab
        CentrosomeTrackingPanel       matlab.ui.container.Panel
        Button_3                      matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        SearchWidth                   matlab.ui.control.NumericEditField
        SearchWidthEditFieldLabel     matlab.ui.control.Label
        LocateSwitch                  matlab.ui.control.Switch
        LocateSwitchLabel             matlab.ui.control.Label
        ChannelDropDown               matlab.ui.control.DropDown
        ChannelDropDownLabel          matlab.ui.control.Label
        SelectTiffStackButton         matlab.ui.control.Button
        SideAxes                      matlab.ui.control.UIAxes
        TopDownAxes                   matlab.ui.control.UIAxes
        TrackRingTab                  matlab.ui.container.Tab
        RingSetupWarning              matlab.ui.control.Label
        RingTrackingControls          matlab.ui.container.Panel
        Button                        matlab.ui.control.Button
        ring_width                    matlab.ui.control.NumericEditField
        RingWidthmumEditFieldLabel    matlab.ui.control.Label
        FluoPercentile                matlab.ui.control.NumericEditField
        FluoPercentileEditFieldLabel  matlab.ui.control.Label
        AutoscaleROICheckBox          matlab.ui.control.CheckBox
        ManualAlignButton             matlab.ui.control.Button
        FitFrameButton                matlab.ui.control.Button
        UIAxesRing3D                  matlab.ui.control.UIAxes
        UIAxesRing2D                  matlab.ui.control.UIAxes
        UIAxesFull3D                  matlab.ui.control.UIAxes
        TrackCentrosomesTab           matlab.ui.container.Tab
        RingSetupWarning_2            matlab.ui.control.Label
        ReportTab                     matlab.ui.container.Tab
        CellDropDown                  matlab.ui.control.DropDown
        CellDropDownLabel             matlab.ui.control.Label
        ImportCellBoundaryDataButton  matlab.ui.control.Button
        YDropDown                     matlab.ui.control.DropDown
        YDropDownLabel                matlab.ui.control.Label
        XDropDown                     matlab.ui.control.DropDown
        XDropDownLabel                matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        data % 4D data array, possibly multichannel
        roi_data % 4D data array
        N_frames % size(data,4)
        N_channels % size(data,5)
        channel % channel to display/analyze
        TopDownImage
        TopDownListener
        SideImage
        SideListener
        cmap = 'parula';
        TopDownROI
        SideROI
        TopDownPts=[]
        SideLines=[]
        TopDownLines=[]
        scale % Description
        %x0=[0.0372, 2.7652];
        x0=[0, .8*pi];
        xopt=[nan nan];
        pos_inds
        all_pos
        enabled_tabs={'Setup'};
        fittings={};
        curr_frame=1;
    end


    methods (Access = private)

        function PreviewFrame(app)

            frame_data = app.data(:,:,:,app.curr_frame,app.channel);
            app.TopDownImage.CData = mip(frame_data);
            if isempty(app.TopDownROI) && length(app.TopDownPts)<2
                app.SideImage.CData = mip(frame_data,2)';
            else
                if strcmp(app.LocateSwitch.Value,'Centrosomes')
                    HighlightCentrosomes(app)
                else
                    SideView(app, get(app.TopDownROI,'position'), app.curr_frame)
                end
            end

        end

        function FitRingAndPlot(app)

            if isempty(app.SideROI)
                return
            end

            rectxy=get(app.TopDownROI, 'position');
            rect2=get(app.SideROI, 'position');
            coords = [sum(rectxy([1,1,3])) sum(rectxy([2,2,4])) sum(rect2([2,2,4]))]/2;
%             coords=((1./app.scale([2,1,3]))'.*coords')';
%             coords = round(app.scale([2,1]))


            objectsPrev=coords+0.5;
            objectSize=[rectxy(3:4) rect2(4)]/2;

            objectsPrev = objectsPrev([2,1,3]);
            objectSize = objectSize([2,1,3]);


            objectSize=objectSize./app.scale([2,1,3]);
            objectsPrev=objectsPrev./app.scale([2,1,3]);


            vol_data = processRawRingData(app, objectsPrev, objectSize);
            plotRingRawData(app, vol_data);
            plotFitRing(app, vol_data, objectsPrev, objectSize, app.curr_frame);
        end

        function HandleNewFrame(app)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                case 'Setup'
                    PreviewFrame(app)
                case 'Track Ring'
                    FitRingAndPlot(app)
            end
            drawnow
        end

        function addTopDownROI(app, event)
            if isempty(app.TopDownROI) || ~isgraphics(app.TopDownROI)
                ax=app.TopDownAxes;
                bounds=[xlim(ax);ylim(ax)];
                pt = event.IntersectionPoint;
                pos = [pt(1), pt(2),(bounds(1,2)-pt(1))/2,(bounds(2,2)-pt(2))/2];
                roi = drawrectangle(ax,'Position',pos,'Color','y','LineWidth',1);

                app.TopDownROI = roi;
                SideView(app,pos)
                addlistener(roi,'MovingROI',createCallbackFcn(app, @TopROIEvent, true));
                addlistener(roi,'ROIMoved',createCallbackFcn(app, @TopROIEvent, true));

                set(app.SideImage,'ButtonDownFcn',createCallbackFcn(app, @addSideROI, true));
            end
        end

        function addSideROI(app, event)
            ax=app.SideAxes;
            bounds=[xlim(ax);ylim(ax)];
            pt = event.IntersectionPoint;
            pos = [pt(1), pt(2),(bounds(1,2)-pt(1))/2,(bounds(2,2)-pt(2))/2];
            roi = drawrectangle(ax,'Position',pos,'Color','y','LineWidth',1);
            app.SideROI = roi;

            EnableRingTrackTab(app)
        end

        function addTopDownPtPair(app, event)
            if length(app.TopDownPts)<2 || ~isgraphics(app.TopDownPts(1))
                sz = 5;
                %
                %                 roi = drawellipse(app.TopDownAxes,'Center',event.IntersectionPoint(1:2), ...
                %                     'InteractionsAllowed', 'translate','Color','y','LineWidth',1,...
                %                     'Markersize',0.0001, 'FixedAspectRatio',1, 'SemiAxes', [sz(2), sz(1)]*0.05);

                if isempty(app.TopDownPts)
                    color = 'y';
                else
                    color = 'r';
                end
                roi = drawpoint(app.TopDownAxes,'Position',event.IntersectionPoint(1:2), ...
                    'Color',color,...
                    'Markersize',sz,'Deletable', false);

                addlistener(roi,'MovingROI', PtDragging(app, 1))
                addlistener(roi,'ROIMoved', PtDragging(app, sz))
                app.TopDownPts = [app.TopDownPts roi];
                %                 SideView(app,pos)
                %                 addlistener(roi,'MovingROI',createCallbackFcn(app, @TopROIEvent, true));
                %                 addlistener(roi,'ROIMoved',createCallbackFcn(app, @TopROIEvent, true));

                %                 app.SideImage.ButtonDownFcn=createCallbackFcn(app, @addSideROI, true);
            end
        end

        function HighlightCentrosomes(app)
            if length(app.TopDownPts)==2 && strcmp(app.LocateSwitch.Value,'Centrosomes')
                pos1=fliplr(get(app.TopDownPts(1),'Position'));
                pos2=fliplr(get(app.TopDownPts(2),'Position'));
                xy_coords = [pos1; pos2];


                [~,max_spread] = max(abs(diff(xy_coords)));
                min_spread = 1*(max_spread==2) + 2*(max_spread==1);
                inds = floor(min(xy_coords(:,min_spread))/app.scale(min_spread)):ceil(max(xy_coords(:,min_spread))/app.scale(min_spread));

                inds2 = ~slice_mask(app.all_pos, xy_coords(1,:), xy_coords(2,:), 1);
                data = app.data(:,:,:,app.curr_frame, app.channel);
                inds3=sub2ind(size(app.data,[1,2,3]), app.pos_inds(inds2, 1), app.pos_inds(inds2, 2), app.pos_inds(inds2, 3));

                data(inds3)=0;

                if max_spread==2
                    mip_1 = mip(data,1);
                    drx='y';
                else
                    mip_1 = mip(data,2);
                    drx='x';
                end

                app.SideImage.CData=mip_1';
                xdata = (1:(size(mip_1',2)))*app.scale(max_spread);
                app.SideAxes.XLim=[xdata(1), xdata(end)+app.scale(max_spread)];
                app.SideImage.XData=xdata;
                colors=['y', 'r'];
                if isempty(app.SideLines)
                    for i = 1:size(xy_coords,1)
                        app.SideLines(i) = line( app.SideAxes, [1 1]*xy_coords(i,max_spread),[0,size(app.data,3)+1],'Color',colors(i));
                    end
                else
                    
                    for i = 1:size(xy_coords,1)
                        set(app.SideLines(i),'XData',[1 1]*xy_coords(i,max_spread));
                    end
                end

                m=slope(fliplr(pos1), fliplr(pos2));
                    b=offset(fliplr(pos1), fliplr(pos2));
%                     refline(app.TopDownAxes, m,b);

                    xmin=0;
                    xmax=size(data,2)*app.scale(2);
                    
                    ymin=0;
                    ymax=size(data,1)*app.scale(1);

                    if b<ymax
                        y0=b;
                        x0=0;
                    else
                        y0=ymax;
                        x0=(ymax-b)/m;
                    end
                    
                    y1=b+xmax*m;
                    if y1>ymax
                        y1=ymax;
                        x1=(ymax-b)/m;
                    else
                        x1=xmax;
                    end
                    disp(m)
                if isempty(app.TopDownLines)
                    
                    app.TopDownLines(1)=line(app.TopDownAxes, [x0, x1], [y0, y1],'Color','yellow');
                    app.TopDownLines(2)=line(app.TopDownAxes, [x0, x1], [y0, y1],'Color','red');


                end

                w=app.SearchWidth.Value/2;
                delta_x = w*sqrt(1/(1+(1/m)^2));
                delta_y = -delta_x/m;
                set(app.TopDownLines(1),'XData', [x0, x1] + delta_x,'YData', [y0, y1] + delta_y);
                set(app.TopDownLines(2),'XData', [x0, x1] - delta_x,'YData', [y0, y1] - delta_y);

                drawnow limitrate
            end
        end

        function out = PtDragging(app, sz)
            function inner(pt, evt)
                pt.MarkerSize=sz;

                HighlightCentrosomes(app)
                    
                end
                out=@inner
            end

            function EnableRingTrackTab(app)


                app.enabled_tabs{end+1}='Track Ring';
                app.RingSetupWarning.Visible='off';
                app.RingSetupWarning.Enable='off';
                app.UIAxesFull3D.Visible='on';
                app.UIAxesRing3D.Visible='on';
                app.UIAxesRing2D.Visible='on';
                app.RingTrackingControls.Visible='on';

                xlabel(app.UIAxesRing3D,'$\hat{x}$','interpreter','latex');
                ylabel(app.UIAxesRing3D,'$\hat{y}$','interpreter','latex');
                zlabel(app.UIAxesRing3D,'$\hat{z}$','interpreter','latex');


                xlim(app.UIAxesFull3D,[0,size(app.data,1)]*app.scale(1))
                ylim(app.UIAxesFull3D,[0,size(app.data,2)]*app.scale(2))
                zlim(app.UIAxesFull3D,[-size(app.data,3),0]*app.scale(3))


            end


            function SideView(app, rectxy, frame)
                ix=floor(rectxy(1)/app.scale(2));
                lx=ceil(rectxy(3)/app.scale(2));
                iy=floor(rectxy(2)/app.scale(1));
                ly=ceil(rectxy(4)/app.scale(1));

                [~,max_spread]=max(rectxy(3:4));
                if nargin==2
                    frame = round(app.FrameSlider.Value);
                end
                data = mip(app.data(iy:(iy+ly),ix:(ix+lx),:,frame,app.channel), max_spread)';


                app.SideImage.CData = data;

                app.SideAxes.XLim=[1, size(data,2)];


            end

            function applyScale(app)
                
                app.scale=[app.xscale.Value, app.yscale.Value, app.zscale.Value];
                app.all_pos = app.pos_inds .* app.scale;

                app.TopDownAxes.XLim=[1, size(app.data,2)*app.scale(2)];
                app.TopDownAxes.YLim=[0.5, size(app.data,1)*app.scale(1)];
                
                app.TopDownImage.XData = (1:size(app.data,2))*app.scale(2);
                app.TopDownImage.YData = (1:size(app.data,1))*app.scale(1);

            app.SideAxes.YLim=[0.5, size(app.data,3)*app.scale(3)];
            app.SideAxes.XLim=[1, size(app.data,1)]*app.scale(2);
            end

            function TopROIEvent(app, evt)
                SideView(app, evt.CurrentPosition);
            end

            function plotFitRing(app, data, objectsPrev, objectSize, i)
                persistent AZ EL Ring2DLine Ring3DLine Full3DLine

                phi=data.phi;
                theta=data.theta;
                XY=data.XY;
                Z=data.Z;


                Zbar=mean(Z);

                near_ring = abs(Z-Zbar)<app.ring_width.Value;


                p=EllipseDirectFit(XY(near_ring,:));
                data.p=p;
                fitting=struct('phi', phi, 'theta', theta, 'p', p);
                app.fittings{app.curr_frame}=fitting;

                xysamp=sampleEllipse2D(p);

                Ropt=[r_hat(phi, theta)', phi_hat(phi, theta)', theta_hat(phi, theta)'];

                xyzsamp=[xysamp, repmat(Zbar,[size(xysamp,1),1])];
                %                 xyzsamp = xyzsamp + [0,0, Zbar];

                xyzsamp_orig= (Ropt*xyzsamp')' + (objectsPrev-objectSize).*app.scale ;


                hold(app.UIAxesRing2D,'on');

                if isempty(Ring2DLine)
                    Ring2DLine=plot3(app.UIAxesRing2D, xysamp(:,1), xysamp(:,2), randn([size(xysamp,1), 1])*0.001*var(xyzsamp(:,3)), 'LineStyle','-','Color','g','LineWidth',3);
                    view(app.UIAxesRing2D,0,90);
                else
                    Ring2DLine.XData=xysamp(:,1);
                    Ring2DLine.YData=xysamp(:,2);
                end

                hold(app.UIAxesRing2D,'off')


                hold(app.UIAxesRing3D,'on')

                if isempty(Ring3DLine)
                    Ring3DLine=plot3(app.UIAxesRing3D, xyzsamp(:,1),xyzsamp(:,2),xyzsamp(:,3),'-g', 'LineWidth', 3);
                else
                    Ring3DLine.XData=xyzsamp(:,1);
                    Ring3DLine.YData=xyzsamp(:,2);
                    Ring3DLine.ZData=xyzsamp(:,3);
                end
                % plot3(ax2, xyzsamp0(:,1),xyzsamp0(:,2),xyzsamp0(:,3)+Zbar,'--k')
                hold(app.UIAxesRing3D,'off')


                hold(app.UIAxesFull3D,'on')
                title(app.UIAxesFull3D,'Full 3D View')
                if isempty(Full3DLine)
                    Full3DLine=plot3(app.UIAxesFull3D, xyzsamp_orig(:,1),xyzsamp_orig(:,2),-xyzsamp_orig(:,3),'-g', 'LineWidth', 3);
                else
                    Full3DLine.XData=xyzsamp_orig(:,1);
                    Full3DLine.YData=xyzsamp_orig(:,2);
                    Full3DLine.ZData=-xyzsamp_orig(:,3);
                end
                hold(app.UIAxesFull3D,'off')
                %                 xlabel(app.UIAxesFull3D,'X');ylabel(app.UIAxesFull3D,'Y');zlabel(app.UIAxesFull3D,'Z');

                if ~isempty(AZ)
                    view(app.UIAxesFull3D,AZ,EL)
                else
                    view(app.UIAxesFull3D,45,60)
                end


                %                 drawnow
                % pause(4)
                [AZ,EL] = view(app.UIAxesFull3D);

            end

            function plotRingRawData(app, data)
                persistent Ring2DImage Ring3DImage Full3DImage
                intensity = data.intensity;
                full_vol = data.full_vol;

                XY=data.XY;
                Z=data.Z;

                if isempty(Ring2DImage)
                    Ring2DImage = scatter(app.UIAxesRing2D, XY(:,1), XY(:,2),'.');
                    view(app.UIAxesRing2D,0,90)
                else
                    Ring2DImage.XData = XY(:,1);
                    Ring2DImage.YData = XY(:,2);
                end
                markersize=24*scaleVals(intensity,0.5);
                if isempty(Ring3DImage)
                    Ring3DImage = scatter3(app.UIAxesRing3D, XY(:,1),XY(:,2),Z,'Marker','.', 'SizeData', markersize);
                else
                    Ring3DImage.XData = XY(:,1);
                    Ring3DImage.YData = XY(:,2);
                    Ring3DImage.ZData = Z;
                    Ring3DImage.SizeData = markersize;
                end
                bright = full_vol(:)>=intensity(1);
                bright_pos = app.all_pos(bright,:);
                markersize=24*scaleVals(full_vol(bright),0.5);
                if isempty(Full3DImage)
                    Full3DImage = scatter3(app.UIAxesFull3D, bright_pos(:,1),bright_pos(:,2), -bright_pos(:,3),'Marker','.', 'SizeData', markersize);
                else
                    Full3DImage.XData = bright_pos(:,1);
                    Full3DImage.YData = bright_pos(:,2);
                    Full3DImage.ZData = -bright_pos(:,3);
                    Full3DImage.SizeData = markersize;
                end
            end

            function results=processRawRingData(app,objectsPrev, objectSize)

                full_vol = app.data(:,:,:,app.curr_frame,app.channel);
                ring_vol = getVol(full_vol, objectsPrev, objectSize);
%                 ring_vol = getVol(full_vol, round(objectsPrev./app.scale), round(objectSize./app.scale));
                % ring_vol = data(:,:,:,34,1);
                pos = getPos(ring_vol)-1;
                [~, i_sorted] =  sort(ring_vol(:));

                %%
                N_take = round(app.FluoPercentile.Value*length(i_sorted)/100);
                i_take = i_sorted(end-(N_take-1):end);
                intensity = ring_vol(i_take);
                pos_take = pos(i_take,:).*app.scale;


                f=@(phi,theta) var(theta_hat(phi,theta) * pos_take');
                if any(isnan(app.xopt))
                    x0=app.x0;
                else
                    x0=app.xopt;
                end
                app.xopt=fminsearch(@(x) f(x(1),x(2)),app.x0);


                phi=app.xopt(1);
                theta=app.xopt(2);


                XY=[r_hat(phi,theta) * pos_take'; phi_hat(phi,theta) * pos_take']';


                Z=(theta_hat(phi,theta) * pos_take')';

                results=struct('intensity', intensity,'full_vol',full_vol,'XY',XY,'Z',Z,'phi',phi,'theta',theta);


            end


        end

        methods (Static)

        end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectTiffStackButton
        function SelectTiffStackButtonPushed(app, event)
            [nm, p]=uigetfile('*.tif','Select image file');
            fn=[p nm];
            [data, scale]= read_tiff(fn);

            app.data = data;
            app.pos_inds = getPos(app.data(:,:,:,1,1));

            app.xscale.Value=scale(1);
            app.xscale.Enable="on";
            app.yscale.Value=scale(2);
            app.yscale.Enable="on";
            app.zscale.Value=scale(3);
            app.zscale.Enable="on";


            disp('Done loading tiff stack')


            app.N_frames=size(data,4);
            app.FrameSlider.Enable=true;
            app.FrameSliderLabel.Enable=true;
            app.FrameSlider.Limits=[1,app.N_frames];

            app.fittings=cell(app.N_frames,1);

            N_ticks = min(app.N_frames,20);
            MajorTicks=round(linspace(1,app.N_frames,N_ticks));
            app.FrameSlider.MajorTicks=MajorTicks;
            app.FrameSlider.MinorTicks=round(interp1(1:N_ticks,MajorTicks,1:0.2:N_ticks));

            app.LocateSwitch.Enable=true;
            app.LocateSwitchLabel.Enable=true;

            app.N_channels=size(data,5);
            app.ChannelDropDown.Items=string(1:app.N_channels);
            app.ChannelDropDown.Enable=true;
            app.ChannelDropDownLabel.Enable=true;

            app.channel=1;


            app.SideAxes.Visible=true;
            app.TopDownAxes.Visible=true;

            frame_data = app.data(:,:,:,1,app.channel);


            app.TopDownImage = imagesc(app.TopDownAxes , mip(frame_data));
            colormap(app.TopDownAxes,app.cmap)
            app.SideImage = imagesc(app.SideAxes, mip(frame_data, 2)');
            disableDefaultInteractivity(app.SideAxes)
            colormap(app.SideAxes,app.cmap)

            LocateSwitchValueChanged(app,[])

            applyScale(app);

        end

        % Button down function: TopDownAxes
        function TopDownAxesButtonDown(app, event)
            disp('this is the button down callback for the top-down preview panel')
        end

        % Button down function: SideAxes
        function SideAxesButtonDown(app, event)
            disp('side clicked')
        end

        % Button down function: TrackRingTab
        function TrackRingTabButtonDown(app, event)

            FitRingAndPlot(app)

        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.curr_frame=round(app.FrameSlider.Value);
            HandleNewFrame(app)
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            FrameSliderValueChanged(app, event)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            HandleNewFrame(app)
        end

        % Value changed function: yscale
        function yscaleValueChanged(app, event)
            applyScale(app)
        end

        % Value changed function: xscale
        function xscaleValueChanged(app, event)
            applyScale(app)
        end

        % Value changed function: zscale
        function zscaleValueChanged(app, event)
            applyScale(app)
        end

        % Button pushed function: ManualAlignButton
        function ManualAlignButtonPushed(app, event)
            [az,el]=view(app.UIAxesRing3D);
            disp(pi*[az,el]/180)
            disp([app.x0, app.xopt])
        end

        % Callback function: AppUIFigure, AppUIFigure
        function AppUIFigureKeyPress(app, event)
            value = app.FrameSlider.Value; % get the slider value
            key = event.Key; % get the pressed key value
            changed=false;
            if strcmp(key,'leftarrow') && value-1>=app.FrameSlider.Limits(1)
                value = value-1; % left value
                changed=true;
            elseif strcmp(key,'rightarrow') && value+1<=app.FrameSlider.Limits(2)
                value = value+1; % right value
                changed=true;
            end

            if changed
                app.FrameSlider.Value = value; % set the slider value
                FrameSliderValueChanged(app, event); % execute the slider callback
            end

        end

        % Value changed function: LocateSwitch
        function LocateSwitchValueChanged(app, event)

            value = app.LocateSwitch.Value;
            app.SideImage.ButtonDownFcn = '';
            app.TopDownImage.ButtonDownFcn = '';


            if strcmp(value, 'Ring')

                if ~isempty(app.TopDownROI)
                    app.TopDownROI.Visible = 1;
                else
                    app.TopDownImage.ButtonDownFcn = createCallbackFcn(app, @addTopDownROI, true);
                end
                if ~isempty(app.SideROI)
                    app.SideROI.Visible = 1;
                end

                for roi=app.TopDownPts
                    roi.Visible=0;
                end
                app.CentrosomeTrackingPanel.Visible = false;
                app.CentrosomeTrackingPanel.Enable  = 'off';
            else
                app.TopDownImage.ButtonDownFcn = createCallbackFcn(app, @addTopDownPtPair, true);
                if ~isempty(app.TopDownROI)
                    app.TopDownROI.Visible = 0;
                end
                if ~isempty(app.SideROI)
                    app.SideROI.Visible = 0;
                end

                for roi=app.TopDownPts
                    roi.Visible=1;
                end
                app.CentrosomeTrackingPanel.Visible = true;
                app.CentrosomeTrackingPanel.Enable  = 'on';
            end
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;
            app.channel = str2double(value);
            HandleNewFrame(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AppUIFigure = uifigure('Visible', 'off');
            app.AppUIFigure.Name = 'Ring Tracker 3D (MATLAB)';
            app.AppUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @AppUIFigureKeyPress, true);
            app.AppUIFigure.KeyPressFcn = createCallbackFcn(app, @AppUIFigureKeyPress, true);
            app.MasterGrid = uigridlayout(app.AppUIFigure);
            app.TabGroup = uitabgroup(app.MasterGrid);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.TopDownAxes = uiaxes(app.SetupTab);
            title(app.TopDownAxes, 'Max. Int. Proj. (xy-view) - Please place ROI around ring')
            xlabel(app.TopDownAxes, 'X')
            ylabel(app.TopDownAxes, 'Y')
            zlabel(app.TopDownAxes, 'Z')
            app.TopDownAxes.ButtonDownFcn = createCallbackFcn(app, @TopDownAxesButtonDown, true);
            app.TopDownAxes.Visible = 'off';
            app.SideAxes = uiaxes(app.SetupTab);
            title(app.SideAxes, 'Max. Int. Proj. (xz-view) - Please place ROI around ring')
            xlabel(app.SideAxes, 'X')
            ylabel(app.SideAxes, 'Z')
            zlabel(app.SideAxes, 'Z')
            app.SideAxes.ButtonDownFcn = createCallbackFcn(app, @SideAxesButtonDown, true);
            app.SideAxes.Visible = 'off';
            app.SelectTiffStackButton = uibutton(app.SetupTab, 'push');
            app.SelectTiffStackButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTiffStackButtonPushed, true);
            app.SelectTiffStackButton.Text = 'Select Tiff Stack';
            app.ChannelDropDownLabel = uilabel(app.SetupTab);
            app.ChannelDropDownLabel.Enable = 'off';
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.SetupTab);
            app.ChannelDropDown.Items = {'1', '2', '3', '4'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Enable = 'off';
            app.ChannelDropDown.Placeholder = '-';
            app.ChannelDropDown.Value = '1';
            app.LocateSwitchLabel = uilabel(app.SetupTab);
            app.LocateSwitchLabel.Enable = 'off';
            app.LocateSwitchLabel.Text = 'Locate';
            app.LocateSwitch = uiswitch(app.SetupTab, 'slider');
            app.LocateSwitch.Items = {'Ring', 'Centrosomes'};
            app.LocateSwitch.ValueChangedFcn = createCallbackFcn(app, @LocateSwitchValueChanged, true);
            app.LocateSwitch.Enable = 'off';
            app.LocateSwitch.Value = 'Ring';
            app.CentrosomeTrackingPanel = uipanel(app.SetupTab);
            app.CentrosomeTrackingPanel.Enable = 'off';
            app.CentrosomeTrackingPanel.Title = 'Centrosome Tracking';
            app.CentrosomeTrackingPanel.Visible = 'off';
            app.SearchWidthEditFieldLabel = uilabel(app.CentrosomeTrackingPanel);
            app.SearchWidthEditFieldLabel.Text = 'Search Width';
            app.SearchWidth = uieditfield(app.CentrosomeTrackingPanel, 'numeric');
            app.SearchWidth.Limits = [0 Inf];
            app.SearchWidth.Value = 3;
            app.Button_2 = uibutton(app.CentrosomeTrackingPanel, 'push');
            app.Button_2.Tooltip = {'Track centrosomes to next frame.'};
            app.Button_2.Text = '>';
            app.Button_3 = uibutton(app.CentrosomeTrackingPanel, 'push');
            app.Button_3.Tooltip = {'Track centrosomes until end.'};
            app.Button_3.Text = '>>|';
            app.TrackRingTab = uitab(app.TabGroup);
            app.TrackRingTab.Title = 'Track Ring';
            app.TrackRingTab.ButtonDownFcn = createCallbackFcn(app, @TrackRingTabButtonDown, true);
            app.UIAxesFull3D = uiaxes(app.TrackRingTab);
            title(app.UIAxesFull3D, 'Full 3D View')
            xlabel(app.UIAxesFull3D, 'X')
            ylabel(app.UIAxesFull3D, 'Y')
            zlabel(app.UIAxesFull3D, 'Z')
            app.UIAxesFull3D.Visible = 'off';
            app.UIAxesRing2D = uiaxes(app.TrackRingTab);
            title(app.UIAxesRing2D, '2D Projection')
            xlabel(app.UIAxesRing2D, 'X')
            ylabel(app.UIAxesRing2D, 'Y')
            zlabel(app.UIAxesRing2D, 'Z')
            app.UIAxesRing2D.Visible = 'off';
            app.UIAxesRing3D = uiaxes(app.TrackRingTab);
            title(app.UIAxesRing3D, 'Ring-Centered 3D View')
            xlabel(app.UIAxesRing3D, 'X')
            ylabel(app.UIAxesRing3D, 'Y')
            zlabel(app.UIAxesRing3D, 'Z')
            app.UIAxesRing3D.Visible = 'off';
            app.RingTrackingControls = uipanel(app.TrackRingTab);
            app.RingTrackingControls.Title = 'Tracking Controls';
            app.RingTrackingControls.Visible = 'off';
            app.FitFrameButton = uibutton(app.RingTrackingControls, 'push');
            app.FitFrameButton.Enable = 'off';
            app.FitFrameButton.Text = 'Fit Frame';
            app.ManualAlignButton = uibutton(app.RingTrackingControls, 'push');
            app.ManualAlignButton.ButtonPushedFcn = createCallbackFcn(app, @ManualAlignButtonPushed, true);
            app.ManualAlignButton.Text = 'Manual Align';
            app.AutoscaleROICheckBox = uicheckbox(app.RingTrackingControls);
            app.AutoscaleROICheckBox.Text = 'Autoscale ROI';
            app.FluoPercentileEditFieldLabel = uilabel(app.RingTrackingControls);
            app.FluoPercentileEditFieldLabel.Interpreter = 'latex';
            app.FluoPercentileEditFieldLabel.Text = 'Fluo. Percentile';
            app.FluoPercentile = uieditfield(app.RingTrackingControls, 'numeric');
            app.FluoPercentile.Limits = [0 100];
            app.FluoPercentile.Value = 5;
            app.RingWidthmumEditFieldLabel = uilabel(app.RingTrackingControls);
            app.RingWidthmumEditFieldLabel.Interpreter = 'latex';
            app.RingWidthmumEditFieldLabel.Text = 'Ring Width ($\mu$m)';
            app.ring_width = uieditfield(app.RingTrackingControls, 'numeric');
            app.ring_width.Limits = [0 Inf];
            app.ring_width.Value = 2;
            app.Button = uibutton(app.RingTrackingControls, 'push');
            app.Button.Enable = 'off';
            app.Button.Text = '>>|';
            app.RingSetupWarning = uilabel(app.TrackRingTab);
            app.RingSetupWarning.Text = 'Please first locate Ring in the Setup tab.';
            app.TrackCentrosomesTab = uitab(app.TabGroup);
            app.TrackCentrosomesTab.Title = 'Track Centrosomes';
            app.RingSetupWarning_2 = uilabel(app.TrackCentrosomesTab);
            app.RingSetupWarning_2.Text = 'Please first locate Centrosomes in the Setup tab.';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.UIAxes4 = uiaxes(app.ReportTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.XDropDownLabel = uilabel(app.ReportTab);
            app.XDropDownLabel.Text = 'X';
            app.XDropDown = uidropdown(app.ReportTab);
            app.YDropDownLabel = uilabel(app.ReportTab);
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.ReportTab);
            app.ImportCellBoundaryDataButton = uibutton(app.ReportTab, 'push');
            app.ImportCellBoundaryDataButton.Text = 'Import Cell Boundary Data';
            app.CellDropDownLabel = uilabel(app.ReportTab);
            app.CellDropDownLabel.Enable = 'off';
            app.CellDropDownLabel.Text = 'Cell #';
            app.CellDropDown = uidropdown(app.ReportTab);
            app.CellDropDown.Enable = 'off';
            app.CellDropDown.Visible = 'off';
            app.ControlGrid = uigridlayout(app.MasterGrid);
            app.FrameGrid = uigridlayout(app.ControlGrid);
            app.FrameSliderLabel = uilabel(app.FrameGrid);
            app.FrameSliderLabel.Text = 'Frame #';
            app.FrameSlider = uislider(app.FrameGrid);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FitProgressLabel = uilabel(app.FrameGrid);
            app.FitProgressLabel.Text = 'Fit Progress';
            app.ProgressImage = uiimage(app.FrameGrid);
            app.ProgressImage.ImageSource = fullfile(pathToMLAPP, 'transparent.png');
            app.ScaleGrid = uigridlayout(app.ControlGrid);
            app.xscaleLabel = uilabel(app.ScaleGrid);
            app.xscaleLabel.Interpreter = 'latex';
            app.xscaleLabel.Text = 'x-scale';
            app.zscale = uieditfield(app.ScaleGrid, 'numeric');
            app.zscale.Limits = [0 Inf];
            app.zscale.ValueChangedFcn = createCallbackFcn(app, @zscaleValueChanged, true);
            app.zscale.Enable = 'off';
            app.zscale.Value = 1;
            app.yscaleLabel = uilabel(app.ScaleGrid);
            app.yscaleLabel.Interpreter = 'latex';
            app.yscaleLabel.Text = 'y-scale';
            app.yscale = uieditfield(app.ScaleGrid, 'numeric');
            app.yscale.Limits = [0 Inf];
            app.yscale.ValueChangedFcn = createCallbackFcn(app, @yscaleValueChanged, true);
            app.yscale.Enable = 'off';
            app.yscale.Value = 1;
            app.xscale = uieditfield(app.ScaleGrid, 'numeric');
            app.xscale.Limits = [0 Inf];
            app.xscale.ValueChangedFcn = createCallbackFcn(app, @xscaleValueChanged, true);
            app.xscale.Enable = 'off';
            app.xscale.Value = 1;
            app.zscaleLabel = uilabel(app.ScaleGrid);
            app.zscaleLabel.Interpreter = 'latex';
            app.zscaleLabel.Text = 'z-scale';
            % Show the figure after all components are created
            app.AppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RingTrackerGrided

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppUIFigure)
        end
    end
end